classdef SoftwareUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        MenuTab                  matlab.ui.container.Tab
        DimensionlessNumberFunlandLabel  matlab.ui.control.Label
        Panel_2                  matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        StokesStButton           matlab.ui.control.Button
        Button9                  matlab.ui.control.Button
        Button8                  matlab.ui.control.Button
        Button7                  matlab.ui.control.Button
        Button6                  matlab.ui.control.Button
        Button5                  matlab.ui.control.Button
        CapillaryCaButton        matlab.ui.control.Button
        MachMaButton             matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        ReynoldsReButton         matlab.ui.control.Button
        ReTab                    matlab.ui.container.Tab
        ReAppText                matlab.ui.control.Label
        ReAppTitle               matlab.ui.control.Label
        ReEqnText                matlab.ui.control.Label
        ReEqnLatex               matlab.ui.control.Label
        ReEqnTitle               matlab.ui.control.Label
        ReInterpretText          matlab.ui.control.Label
        ReInterpretationTitle    matlab.ui.control.Label
        ReMenuButton             matlab.ui.control.Button
        SimulationButton_2       matlab.ui.control.Button
        ReTheoryText             matlab.ui.control.Label
        ReTheoryTitle            matlab.ui.control.Label
        ReSimTab                 matlab.ui.container.Tab
        NextSimulationButton     matlab.ui.control.Button
        DropDown                 matlab.ui.control.DropDown
        ReSliderLabel            matlab.ui.control.Label
        ReSlider                 matlab.ui.control.Slider
        BackButton               matlab.ui.control.Button
        MenuButton_2             matlab.ui.control.Button
        RunButton                matlab.ui.control.Button
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        ReSim2Tab                matlab.ui.container.Tab
        StepHeightSlider         matlab.ui.control.Slider
        StepHeightSliderLabel    matlab.ui.control.Label
        StepWidthKnob_2          matlab.ui.control.Knob
        StepWidthKnob_2Label     matlab.ui.control.Label
        DropDown_2               matlab.ui.control.DropDown
        BackButton_4             matlab.ui.control.Button
        MenuButton_7             matlab.ui.control.Button
        ReSlider_2Label          matlab.ui.control.Label
        ReSlider_2               matlab.ui.control.Slider
        RunButton_3              matlab.ui.control.Button
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes2_2                matlab.ui.control.UIAxes
        StoTab                   matlab.ui.container.Tab
        StEqnText                matlab.ui.control.Label
        StEqnLatex               matlab.ui.control.Label
        StEquationTitle          matlab.ui.control.Label
        StInterpretText          matlab.ui.control.Label
        StInterpretTitle         matlab.ui.control.Label
        StAppTitle               matlab.ui.control.Label
        StAppText                matlab.ui.control.Label
        StTheorytext             matlab.ui.control.Label
        StTheoryTitle            matlab.ui.control.Label
        SimulationButton         matlab.ui.control.Button
        StMenuButton             matlab.ui.control.Button
        StoSimTab                matlab.ui.container.Tab
        Label_2                  matlab.ui.control.Label
        StokesNumberSlider       matlab.ui.control.Slider
        StokesNumberSliderLabel  matlab.ui.control.Label
        RunButton_2              matlab.ui.control.Button
        BackButton_2             matlab.ui.control.Button
        MenuButton_4             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        MaTab                    matlab.ui.container.Tab
        ReAppText_2              matlab.ui.control.Label
        ReAppTitle_2             matlab.ui.control.Label
        ReEqnText_2              matlab.ui.control.Label
        ReEqnLatex_2             matlab.ui.control.Label
        ReEqnTitle_2             matlab.ui.control.Label
        ReInterpretText_2        matlab.ui.control.Label
        ReInterpretationTitle_2  matlab.ui.control.Label
        ReTheoryText_2           matlab.ui.control.Label
        ReTheoryTitle_2          matlab.ui.control.Label
        MenuButton_5             matlab.ui.control.Button
        SimulationButton_3       matlab.ui.control.Button
        MaSimTab                 matlab.ui.container.Tab
        BackButton_3             matlab.ui.control.Button
        MenuButton_6             matlab.ui.control.Button
        UIAxes3                  matlab.ui.control.UIAxes
        CaTab                    matlab.ui.container.Tab
        SimulationButton_4       matlab.ui.control.Button
        ReMenuButton_2           matlab.ui.control.Button
        CaSimTab                 matlab.ui.container.Tab
        MenuButton_8             matlab.ui.control.Button
        BackButton_5             matlab.ui.control.Button
    end

      properties (Access = public)
        SimData % resim
        data
    end
    methods (Access = private)
        
        function results = func(app)
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton, ReynoldsReButton
        function ReButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ReTab;
        end

        % Callback function
        function SliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Button pushed function: RunButton
        function NewReSim(app, event)
        app.SimData = ReSimLid('compute', app.ReSlider.Value);
ReSimLid('plot', app.SimData, app.DropDown.Value, app.UIAxes_2, app.UIAxes2);
        end

        % Button pushed function: BackButton_2, StokesStButton
        function StoButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.StoTab;
        end

        % Button pushed function: MachMaButton, SimulationButton_3
        function MaButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.MaSimTab;
        end

        % Button pushed function: SimulationButton_2
        function SimulationButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.ReSimTab;
        end

        % Button pushed function: MenuButton_2, MenuButton_4, 
        % ...and 6 other components
        function MenuButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.MenuTab;
        end

        % Callback function
        function RunButton_2Pushed(app, event)
            
        end

        % Button pushed function: RunButton_2
        function RunStokesSimButtonPushed(app, event)
            app.UIAxes.Visible = true;
            StSim(app.UIAxes, app.StokesNumberSlider.Value);
        end

        % Button pushed function: SimulationButton
        function SimulationButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.StoSimTab;
        end

        % Value changed function: StokesNumberSlider
        function StokesNumberSliderValueChanged(app, event)
          
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
        ReSimLid('plot', app.SimData, app.DropDown.Value, app.UIAxes_2, app.UIAxes2);


        end

        % Button pushed function: RunButton_3
        function RunButton_3Pushed(app, event)
% Compute
app.data = ReSimStep('compute', app.ReSlider_2.Value, ...
                            app.StepWidthKnob_2.Value/100, ...
                            app.StepHeightSlider.Value/100);

% Plot into app axes. Provide axes from the app to avoid use of gca.
ReSimStep('plot', app.data,app.DropDown_2.Value, app.UIAxes_3, app.UIAxes2_2);


        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            
        end

        % Button pushed function: BackButton_4
        function ReButtonPushed2(app, event)
            app.TabGroup.SelectedTab=app.ReSimTab;
        end

        % Button pushed function: BackButton_3
        function MaBack(app, event)
            app.TabGroup.SelectedTab=app.MaTab;
        end

        % Button pushed function: NextSimulationButton
        function ToReSim2(app, event)
            app.TabGroup.SelectedTab=app.ReSim2Tab;
        end

        % Button pushed function: CapillaryCaButton
        function CaButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.CaTab;
        end

        % Callback function: not associated with a component
        function ReSimulationButton_2Pushed(app, event)
            
        end

        % Button pushed function: SimulationButton_4
        function CaSimPressed(app, event)
            app.TabGroup.SelectedTab=app.CaSimTab;
        end

        % Button pushed function: BackButton_5
        function CaBack(app, event)
            app.TabGroup.SelectedTab=app.CaTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.Panel_2 = uipanel(app.MenuTab);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.ReynoldsReButton = uibutton(app.GridLayout2, 'push');
            app.ReynoldsReButton.ButtonPushedFcn = createCallbackFcn(app, @ReButtonPushed, true);
            app.ReynoldsReButton.Text = 'Reynolds (Re)';
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.Text = '';
            app.MachMaButton = uibutton(app.GridLayout2, 'push');
            app.MachMaButton.ButtonPushedFcn = createCallbackFcn(app, @MaButtonPushed, true);
            app.MachMaButton.Text = 'Mach (Ma)';
            app.CapillaryCaButton = uibutton(app.GridLayout2, 'push');
            app.CapillaryCaButton.ButtonPushedFcn = createCallbackFcn(app, @CaButtonPushed, true);
            app.CapillaryCaButton.Text = 'Capillary (Ca)';
            app.Button5 = uibutton(app.GridLayout2, 'push');
            app.Button5.Text = 'Button5';
            app.Button6 = uibutton(app.GridLayout2, 'push');
            app.Button6.Text = 'Button6';
            app.Button7 = uibutton(app.GridLayout2, 'push');
            app.Button7.Text = 'Button7';
            app.Button8 = uibutton(app.GridLayout2, 'push');
            app.Button8.Text = 'Button8';
            app.Button9 = uibutton(app.GridLayout2, 'push');
            app.Button9.Text = 'Button9';
            app.StokesStButton = uibutton(app.GridLayout2, 'push');
            app.StokesStButton.ButtonPushedFcn = createCallbackFcn(app, @StoButtonPushed, true);
            app.StokesStButton.Text = 'Stokes (St)';
            app.DimensionlessNumberFunlandLabel = uilabel(app.MenuTab);
            app.DimensionlessNumberFunlandLabel.Text = 'Dimensionless Number Funland';
            app.ReTab = uitab(app.TabGroup);
            app.ReTab.Title = 'Re';
            app.ReTheoryTitle = uilabel(app.ReTab);
            app.ReTheoryTitle.Text = 'Theory';
            app.ReTheoryText = uilabel(app.ReTab);
            app.ReTheoryText.Text = 'The Reynolds number (Re), compares the inertial forces to viscous forces in a fluid flow.';
            app.SimulationButton_2 = uibutton(app.ReTab, 'push');
            app.SimulationButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimulationButton_2Pushed, true);
            app.SimulationButton_2.Text = 'Simulation';
            app.ReMenuButton = uibutton(app.ReTab, 'push');
            app.ReMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.ReMenuButton.Text = 'Menu';
            app.ReInterpretationTitle = uilabel(app.ReTab);
            app.ReInterpretationTitle.Text = 'Interpretation';
            app.ReInterpretText = uilabel(app.ReTab);
            app.ReInterpretText.Text = {'A low Re (<2000) signifies a laminar (smooth) flow whereas a high Re (>4000) signifies a turbulent (chaotic) flow dominated by eddies. '; 'Between 2000<Re<4000 exists a transitional region where small disturbances can cause turbulence.'; ''};
            app.ReEqnTitle = uilabel(app.ReTab);
            app.ReEqnTitle.Text = 'Equation';
            app.ReEqnLatex = uilabel(app.ReTab);
            app.ReEqnLatex.Interpreter = 'latex';
            app.ReEqnLatex.Text = '$$Re = \frac{\rho U L}{\mu}$$';
            app.ReEqnText = uilabel(app.ReTab);
            app.ReEqnText.Text = 'where ρ is the fluid density, U is the free flow velocity, L is the characteristic length, and μ is the dynamic viscosity of the fluid.';
            app.ReAppTitle = uilabel(app.ReTab);
            app.ReAppTitle.Text = 'Application';
            app.ReAppText = uilabel(app.ReTab);
            app.ReAppText.Text = 'The Reynolds number is widely used in industry to predict and control fluid flow behavior in pipes, ducts, and around objects. It helps engineers determine whether flow is laminar or turbulent, influencing design choices for pumps, pipelines, heat exchangers, and aerodynamic surfaces. In chemical and process industries, it guides mixing efficiency, flow metering, and reactor design. In aerospace and automotive applications, it informs drag prediction and flow separation control. By comparing inertial to viscous forces, the Reynolds number enables scalable testing and accurate modeling of real-world fluid systems, ensuring optimal performance and energy efficiency across engineering applications.';
            app.ReSimTab = uitab(app.TabGroup);
            app.ReSimTab.Title = 'ReSim';
            app.UIAxes2 = uiaxes(app.ReSimTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.ReSimTab);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.RunButton = uibutton(app.ReSimTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @NewReSim, true);
            app.RunButton.Text = 'Run';
            app.MenuButton_2 = uibutton(app.ReSimTab, 'push');
            app.MenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton_2.Text = 'Menu';
            app.BackButton = uibutton(app.ReSimTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @ReButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ReSlider = uislider(app.ReSimTab);
            app.ReSlider.Limits = [10 700];
            app.ReSlider.Value = 10;
            app.ReSliderLabel = uilabel(app.ReSimTab);
            app.ReSliderLabel.Text = 'Re';
            app.DropDown = uidropdown(app.ReSimTab);
            app.DropDown.Items = {'Pressure', 'Horizontal Velocity', 'Vertical Velocity', 'Absolute Velocity'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Pressure';
            app.NextSimulationButton = uibutton(app.ReSimTab, 'push');
            app.NextSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ToReSim2, true);
            app.NextSimulationButton.Text = 'Next Simulation';
            app.ReSim2Tab = uitab(app.TabGroup);
            app.ReSim2Tab.Title = 'ReSim2';
            app.UIAxes2_2 = uiaxes(app.ReSim2Tab);
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes_3 = uiaxes(app.ReSim2Tab);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.RunButton_3 = uibutton(app.ReSim2Tab, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunButton_3Pushed, true);
            app.RunButton_3.Text = 'Run';
            app.ReSlider_2 = uislider(app.ReSim2Tab);
            app.ReSlider_2.Limits = [10 100];
            app.ReSlider_2.Value = 10;
            app.ReSlider_2Label = uilabel(app.ReSim2Tab);
            app.ReSlider_2Label.Text = 'Re';
            app.MenuButton_7 = uibutton(app.ReSim2Tab, 'push');
            app.MenuButton_7.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton_7.Text = 'Menu';
            app.BackButton_4 = uibutton(app.ReSim2Tab, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReButtonPushed2, true);
            app.BackButton_4.Text = 'Back';
            app.DropDown_2 = uidropdown(app.ReSim2Tab);
            app.DropDown_2.Items = {'Pressure', 'Horizontal Velocity', 'Vertical Velocity', 'Absolute Velocity'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Pressure';
            app.StepWidthKnob_2Label = uilabel(app.ReSim2Tab);
            app.StepWidthKnob_2Label.Text = 'Step Width (%)';
            app.StepWidthKnob_2 = uiknob(app.ReSim2Tab, 'continuous');
            app.StepWidthKnob_2.Limits = [5 75];
            app.StepWidthKnob_2.Value = 5;
            app.StepHeightSliderLabel = uilabel(app.ReSim2Tab);
            app.StepHeightSliderLabel.Text = 'Step Height (%)';
            app.StepHeightSlider = uislider(app.ReSim2Tab);
            app.StepHeightSlider.Limits = [5 75];
            app.StepHeightSlider.MajorTicks = [5 15 25 35 45 55 65 75];
            app.StepHeightSlider.Value = 5;
            app.StoTab = uitab(app.TabGroup);
            app.StoTab.Title = 'Sto';
            app.StMenuButton = uibutton(app.StoTab, 'push');
            app.StMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.StMenuButton.Text = 'Menu';
            app.SimulationButton = uibutton(app.StoTab, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = 'Simulation';
            app.StTheoryTitle = uilabel(app.StoTab);
            app.StTheoryTitle.Text = 'Theory';
            app.StTheorytext = uilabel(app.StoTab);
            app.StTheorytext.Text = 'The Stokes number (St) compares particle inertia to fluid drag forces which describes how well particles follow surrounding fluid motion.';
            app.StAppText = uilabel(app.StoTab);
            app.StAppText.Text = 'The Stokes number predicts how particles behave in a fluid flow, indicating whether they follow the streamlines or move independently. It’s vital in industries like aerosol design, combustion, and filtration, where particle motion, separation efficiency, and droplet dynamics affect performance, safety, and environmental control.';
            app.StAppTitle = uilabel(app.StoTab);
            app.StAppTitle.Text = {'Application'; ''};
            app.StInterpretTitle = uilabel(app.StoTab);
            app.StInterpretTitle.Text = 'Interpretation';
            app.StInterpretText = uilabel(app.StoTab);
            app.StInterpretText.Text = {'Low values St ~0.1 means droplets closely follow fluid streamlines, but as St increases they tend to ignore the streamlines more.'; ''; ''};
            app.StEquationTitle = uilabel(app.StoTab);
            app.StEquationTitle.Text = 'Equation';
            app.StEqnLatex = uilabel(app.StoTab);
            app.StEqnLatex.Interpreter = 'latex';
            app.StEqnLatex.Text = '$$St = \frac{\rho U R}{\mu_g}$$';
            app.StEqnText = uilabel(app.StoTab);
            app.StEqnText.Text = 'where ρ is the density of the fluid, U is the speed of the fluid droplet, R is the droplets radius, and μ_g is the dynamic viscosity of the surrounding fluid (e.g. air).';
            app.StoSimTab = uitab(app.TabGroup);
            app.StoSimTab.Title = 'StoSim';
            app.UIAxes = uiaxes(app.StoSimTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.MenuButton_4 = uibutton(app.StoSimTab, 'push');
            app.MenuButton_4.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton_4.Text = 'Menu';
            app.BackButton_2 = uibutton(app.StoSimTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @StoButtonPushed, true);
            app.BackButton_2.Text = 'Back';
            app.RunButton_2 = uibutton(app.StoSimTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunStokesSimButtonPushed, true);
            app.RunButton_2.Text = 'Run';
            app.StokesNumberSliderLabel = uilabel(app.StoSimTab);
            app.StokesNumberSliderLabel.Text = 'Stokes Number';
            app.StokesNumberSlider = uislider(app.StoSimTab);
            app.StokesNumberSlider.Limits = [0.1 10];
            app.StokesNumberSlider.MajorTicks = [0.1 2 4 6 8 10];
            app.StokesNumberSlider.ValueChangedFcn = createCallbackFcn(app, @StokesNumberSliderValueChanged, true);
            app.StokesNumberSlider.Value = 0.1;
            app.Label_2 = uilabel(app.StoSimTab);
            app.Label_2.Text = 'The particles have an initial horizontal velocity and enter a vortex. You will observe that at low St, the particles have weak inertia and are easily redirected by the vortex. At high St, the particles can persist on their original trajectory.';
            app.MaTab = uitab(app.TabGroup);
            app.MaTab.Title = 'Ma';
            app.SimulationButton_3 = uibutton(app.MaTab, 'push');
            app.SimulationButton_3.ButtonPushedFcn = createCallbackFcn(app, @MaButtonPushed, true);
            app.SimulationButton_3.Text = 'Simulation';
            app.MenuButton_5 = uibutton(app.MaTab, 'push');
            app.MenuButton_5.Text = 'Menu';
            app.ReTheoryTitle_2 = uilabel(app.MaTab);
            app.ReTheoryTitle_2.Text = 'Theory';
            app.ReTheoryText_2 = uilabel(app.MaTab);
            app.ReTheoryText_2.Text = 'The Mach number (Ma) compares the flow velocity to speed of sound in the fluid flow.';
            app.ReInterpretationTitle_2 = uilabel(app.MaTab);
            app.ReInterpretationTitle_2.Text = 'Interpretation';
            app.ReInterpretText_2 = uilabel(app.MaTab);
            app.ReInterpretText_2.Text = {'Values of Ma < 1 means that the flow is subsonic (lower than the speed of sound) where compressibility effects are small.'; 'At a value of Ma = 1, then the flow is sonic (equal to the speed of sound).'; 'Values of Ma > 1 means that it is supersonic (higher than the speed of sound), where shockwaves form and compressibility effects dominate.'; ''};
            app.ReEqnTitle_2 = uilabel(app.MaTab);
            app.ReEqnTitle_2.Text = 'Equation';
            app.ReEqnLatex_2 = uilabel(app.MaTab);
            app.ReEqnLatex_2.Interpreter = 'latex';
            app.ReEqnLatex_2.Text = '$$Ma = \frac{|u|}{c}$$';
            app.ReEqnText_2 = uilabel(app.MaTab);
            app.ReEqnText_2.Text = 'where u is the speed of the fluid and c is the speed of sound.';
            app.ReAppTitle_2 = uilabel(app.MaTab);
            app.ReAppTitle_2.Text = 'Application';
            app.ReAppText_2 = uilabel(app.MaTab);
            app.ReAppText_2.Text = 'The Mach number is crucial in aerospace and automotive industries for designing aircraft, rockets, and high-speed vehicles, influencing aerodynamics, shock wave formation, and structural design under varying flow regimes from subsonic to hypersonic.';
            app.MaSimTab = uitab(app.TabGroup);
            app.MaSimTab.Title = 'MaSim';
            app.UIAxes3 = uiaxes(app.MaSimTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.MenuButton_6 = uibutton(app.MaSimTab, 'push');
            app.MenuButton_6.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton_6.Text = 'Menu';
            app.BackButton_3 = uibutton(app.MaSimTab, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @MaBack, true);
            app.BackButton_3.Text = 'Back';
            app.CaTab = uitab(app.TabGroup);
            app.CaTab.Title = 'Ca';
            app.ReMenuButton_2 = uibutton(app.CaTab, 'push');
            app.ReMenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.ReMenuButton_2.Text = 'Menu';
            app.SimulationButton_4 = uibutton(app.CaTab, 'push');
            app.SimulationButton_4.ButtonPushedFcn = createCallbackFcn(app, @CaSimPressed, true);
            app.SimulationButton_4.Text = 'Simulation';
            app.CaSimTab = uitab(app.TabGroup);
            app.CaSimTab.Title = 'CaSim';
            app.BackButton_5 = uibutton(app.CaSimTab, 'push');
            app.BackButton_5.ButtonPushedFcn = createCallbackFcn(app, @CaBack, true);
            app.BackButton_5.Text = 'Back';
            app.MenuButton_8 = uibutton(app.CaSimTab, 'push');
            app.MenuButton_8.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton_8.Text = 'Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoftwareUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end